classdef ProjectController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ThetaposLabel             matlab.ui.control.Label
        YposLabel                 matlab.ui.control.Label
        XposLabel                 matlab.ui.control.Label
        BackUltraSonicLabel       matlab.ui.control.Label
        RightUltrasonicLabel      matlab.ui.control.Label
        FrontUltraSonicLabel      matlab.ui.control.Label
        LeftUltrasonicLabel       matlab.ui.control.Label
        pitcharmSlider            matlab.ui.control.Slider
        pitcharmSliderLabel       matlab.ui.control.Label
        yawarmSlider              matlab.ui.control.Slider
        yawarmSliderLabel         matlab.ui.control.Label
        rollarmSlider             matlab.ui.control.Slider
        rollarmSliderLabel        matlab.ui.control.Label
        ZarmSlider                matlab.ui.control.Slider
        ZarmSliderLabel           matlab.ui.control.Label
        YarmSlider                matlab.ui.control.Slider
        YarmSliderLabel           matlab.ui.control.Label
        XArmSlider                matlab.ui.control.Slider
        XArmSliderLabel           matlab.ui.control.Label
        SendThetaButton           matlab.ui.control.Button
        SendYButton               matlab.ui.control.Button
        SendXButton               matlab.ui.control.Button
        ThetaEntryEditField       matlab.ui.control.NumericEditField
        ThetaEntryEditFieldLabel  matlab.ui.control.Label
        YEntryEditField           matlab.ui.control.NumericEditField
        YEntryEditFieldLabel      matlab.ui.control.Label
        XEntryEditField           matlab.ui.control.NumericEditField
        XEntryEditFieldLabel      matlab.ui.control.Label
        GripperButtonGroup        matlab.ui.container.ButtonGroup
        UnGripButton              matlab.ui.control.ToggleButton
        GripButton                matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        serialObj % Description
    end
    
    methods (Access = private)
        
        function sendServoValue(app, joint, degrees)
            
            %degrees is a number, maybe int maybe float
            degrees = round(degrees);
            command = sprintf("%S %d", joint, degrees);
            
            fprintf("Sending: %s\n", command);
            writeline(app.serialObj, command);
            
        end
        
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readLine(serialObj))
        
            if strcmpi(message, "B") %% won't be B
               app.LeftUltrasonicLabel.Text = sprintf("Left Ultrasonic = %d", app.Counter);
               app.RightUltrasonicLabel.Text = sprintf("Right Ultrasonic = %d", app.Counter);
               app.FrontUltraSonicLabel.Text = sprintf("Front Ultrasonic = %d", app.Counter);
               app.BackUltraSonicLabel.Text = sprintf("Back Ultrasonic = %d", app.Counter);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc
        app.serialObj = serialport("COM5", 9600);
        flush(app.serialObj);
        configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
        end

        % Callback function
        function XEntryEditFieldValueChanged(app, event)
            value = app.XEntryEditField.Value;
            
        end

        % Value changed function: XArmSlider
        function XArmSliderValueChanged(app, event)
            value = app.XArmSlider.Value;
            
            app.sendServoValue("X", value);
        end

        % Value changing function: XArmSlider
        function XArmSliderValueChanging(app, event)
         
        end

        % Value changed function: YarmSlider
        function YarmSliderValueChanged(app, event)
            value = app.YarmSlider.Value;
            
            app.sendServoValue("Y", value);
        end

        % Value changing function: YarmSlider
        function YarmSliderValueChanging(app, event)
           
        end

        % Value changed function: ZarmSlider
        function ZarmSliderValueChanged(app, event)
            value = app.ZarmSlider.Value;
            
            app.sendServoValue("Z", value);
        end

        % Value changing function: ZarmSlider
        function ZarmSliderValueChanging(app, event)
        
        end

        % Value changed function: rollarmSlider
        function rollarmSliderValueChanged(app, event)
            value = app.rollarmSlider.Value;
            
            app.sendServoValue("Roll", value);
        end

        % Value changing function: rollarmSlider
        function rollarmSliderValueChanging(app, event)
            
        end

        % Value changed function: yawarmSlider
        function yawarmSliderValueChanged(app, event)
            value = app.yawarmSlider.Value;
            
            app.sendServoValue("Yaw", value);
        end

        % Value changing function: yawarmSlider
        function yawarmSliderValueChanging(app, event)
            
        end

        % Value changed function: pitcharmSlider
        function pitcharmSliderValueChanged(app, event)
            value = app.pitcharmSlider.Value;
            
            app.sendServoValue("Pitch", value);
        end

        % Value changing function: pitcharmSlider
        function pitcharmSliderValueChanging(app, event)
           
        end

        % Button pushed function: SendYButton
        function SendYButtonPushed(app, event)
            value = str2double(app.YEntryEditField.Value);
            app.SendYButton(value);
        end

        % Button pushed function: SendXButton
        function SendXButtonPushed(app, event)
            value = str2double(app.XEntryEditField.Value);
            app.SendXButton(value);
        end

        % Button pushed function: SendThetaButton
        function SendThetaButtonPushed(app, event)
            value = str2double(app.ThetaEntryEditField.Value);
            app.SendThetaButton(value);
        end

        % Value changed function: ThetaEntryEditField
        function ThetaEntryEditFieldValueChanged(app, event)
            value = app.ThetaEntryEditField.Value;
            
        end

        % Value changed function: YEntryEditField
        function YEntryEditFieldValueChanged(app, event)
            value = app.YEntryEditField.Value;
            
        end

        % Value changed function: XEntryEditField
        function XEntryEditFieldValueChanged2(app, event)
            value = app.XEntryEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GripperButtonGroup = uibuttongroup(app.UIFigure);
            app.GripperButtonGroup.Title = 'Gripper';
            app.GripButton = uitogglebutton(app.GripperButtonGroup);
            app.GripButton.Text = 'Grip';
            app.GripButton.Value = true;
            app.UnGripButton = uitogglebutton(app.GripperButtonGroup);
            app.UnGripButton.Text = 'Un-Grip';
            app.XEntryEditFieldLabel = uilabel(app.UIFigure);
            app.XEntryEditFieldLabel.Text = 'X Entry';
            app.XEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEntryEditField.ValueChangedFcn = createCallbackFcn(app, @XEntryEditFieldValueChanged2, true);
            app.YEntryEditFieldLabel = uilabel(app.UIFigure);
            app.YEntryEditFieldLabel.Text = 'Y Entry';
            app.YEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEntryEditField.ValueChangedFcn = createCallbackFcn(app, @YEntryEditFieldValueChanged, true);
            app.ThetaEntryEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEntryEditFieldLabel.Text = 'Theta Entry';
            app.ThetaEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEntryEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEntryEditFieldValueChanged, true);
            app.SendXButton = uibutton(app.UIFigure, 'push');
            app.SendXButton.ButtonPushedFcn = createCallbackFcn(app, @SendXButtonPushed, true);
            app.SendXButton.Text = 'Send X';
            app.SendYButton = uibutton(app.UIFigure, 'push');
            app.SendYButton.ButtonPushedFcn = createCallbackFcn(app, @SendYButtonPushed, true);
            app.SendYButton.Text = 'Send Y';
            app.SendThetaButton = uibutton(app.UIFigure, 'push');
            app.SendThetaButton.ButtonPushedFcn = createCallbackFcn(app, @SendThetaButtonPushed, true);
            app.SendThetaButton.Text = 'Send Theta';
            app.XArmSliderLabel = uilabel(app.UIFigure);
            app.XArmSliderLabel.Text = 'X Arm';
            app.XArmSlider = uislider(app.UIFigure);
            app.XArmSlider.ValueChangedFcn = createCallbackFcn(app, @XArmSliderValueChanged, true);
            app.XArmSlider.ValueChangingFcn = createCallbackFcn(app, @XArmSliderValueChanging, true);
            app.YarmSliderLabel = uilabel(app.UIFigure);
            app.YarmSliderLabel.Text = 'Y arm';
            app.YarmSlider = uislider(app.UIFigure);
            app.YarmSlider.ValueChangedFcn = createCallbackFcn(app, @YarmSliderValueChanged, true);
            app.YarmSlider.ValueChangingFcn = createCallbackFcn(app, @YarmSliderValueChanging, true);
            app.ZarmSliderLabel = uilabel(app.UIFigure);
            app.ZarmSliderLabel.Text = 'Z arm';
            app.ZarmSlider = uislider(app.UIFigure);
            app.ZarmSlider.ValueChangedFcn = createCallbackFcn(app, @ZarmSliderValueChanged, true);
            app.ZarmSlider.ValueChangingFcn = createCallbackFcn(app, @ZarmSliderValueChanging, true);
            app.rollarmSliderLabel = uilabel(app.UIFigure);
            app.rollarmSliderLabel.Text = 'roll arm';
            app.rollarmSlider = uislider(app.UIFigure);
            app.rollarmSlider.ValueChangedFcn = createCallbackFcn(app, @rollarmSliderValueChanged, true);
            app.rollarmSlider.ValueChangingFcn = createCallbackFcn(app, @rollarmSliderValueChanging, true);
            app.yawarmSliderLabel = uilabel(app.UIFigure);
            app.yawarmSliderLabel.Text = 'yaw arm';
            app.yawarmSlider = uislider(app.UIFigure);
            app.yawarmSlider.ValueChangedFcn = createCallbackFcn(app, @yawarmSliderValueChanged, true);
            app.yawarmSlider.ValueChangingFcn = createCallbackFcn(app, @yawarmSliderValueChanging, true);
            app.pitcharmSliderLabel = uilabel(app.UIFigure);
            app.pitcharmSliderLabel.Text = 'pitch arm';
            app.pitcharmSlider = uislider(app.UIFigure);
            app.pitcharmSlider.ValueChangedFcn = createCallbackFcn(app, @pitcharmSliderValueChanged, true);
            app.pitcharmSlider.ValueChangingFcn = createCallbackFcn(app, @pitcharmSliderValueChanging, true);
            app.LeftUltrasonicLabel = uilabel(app.UIFigure);
            app.LeftUltrasonicLabel.Text = 'Left Ultrasonic = ';
            app.FrontUltraSonicLabel = uilabel(app.UIFigure);
            app.FrontUltraSonicLabel.Text = 'Front UltraSonic = ';
            app.RightUltrasonicLabel = uilabel(app.UIFigure);
            app.RightUltrasonicLabel.Text = 'Right Ultrasonic = ';
            app.BackUltraSonicLabel = uilabel(app.UIFigure);
            app.BackUltraSonicLabel.Text = 'Back UltraSonic = ';
            app.XposLabel = uilabel(app.UIFigure);
            app.XposLabel.Text = 'X-pos = ';
            app.YposLabel = uilabel(app.UIFigure);
            app.YposLabel.Text = 'Y-pos = ';
            app.ThetaposLabel = uilabel(app.UIFigure);
            app.ThetaposLabel.Text = 'Theta-pos = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end